classdef game_difficult < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Image_2   matlab.ui.control.Image
        Image_3   matlab.ui.control.Image
        Image_4   matlab.ui.control.Image
        Image_5   matlab.ui.control.Image
        Image_6   matlab.ui.control.Image
        Image_7   matlab.ui.control.Image
        Image_8   matlab.ui.control.Image
        Image_9   matlab.ui.control.Image
        Image_10  matlab.ui.control.Image
        Image_11  matlab.ui.control.Image
        Image_12  matlab.ui.control.Image
        Image_13  matlab.ui.control.Image
        Image_14  matlab.ui.control.Image
        Image_15  matlab.ui.control.Image
        Image_16  matlab.ui.control.Image
        Image_17  matlab.ui.control.Image
        Image_18  matlab.ui.control.Image
        Image_19  matlab.ui.control.Image
        Image_20  matlab.ui.control.Image
        Image_21  matlab.ui.control.Image
        Image_22  matlab.ui.control.Image
        Image_23  matlab.ui.control.Image
        Image_24  matlab.ui.control.Image
        Image_25  matlab.ui.control.Image
        Image_26  matlab.ui.control.Image
        Image_27  matlab.ui.control.Image
        Image_28  matlab.ui.control.Image
        Image_29  matlab.ui.control.Image
        Image_30  matlab.ui.control.Image
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0;   % flag決定是否猜對
        random_list = [1,1,2,2,3,3,4,4,5,5,6,6,7,7,8,8,9,9,10,10,11,11,12,12,13,13,14,14,15,15];
        clicked = zeros(1,30);
        jpg = '.jpg';
        complete = 0;
    end
    
    methods (Access = private)
        
        function guess(app)
            guess_answer = 0;   %若猜對則為1
            clicked_image = find(app.clicked);
            if length(clicked_image)>1 && app.random_list(clicked_image(1)) == app.random_list(clicked_image(2))
                guess_answer = 1;
                app.invisible(clicked_image(1))
                app.invisible(clicked_image(2))
                app.complete = app.complete +1;
                if app.complete == 15
                    app.Label.Text = strcat('恭喜完成！！花費', string(toc),'秒');
                end
            elseif length(clicked_image) == 1
                guess_answer = 0;
                app.reset(clicked_image(1));
            else
                guess_answer = 0;
                app.reset(clicked_image(1))
                app.reset(clicked_image(2))
            end
            app.flag = 0;
            app.clicked = zeros(1,30);
        end
        
        function invisible(app, a)
            switch a
                case 1
                    app.Image.Visible = 0;
                case 2
                    app.Image_2.Visible = 0;
                case 3
                    app.Image_3.Visible = 0;
                case 4
                    app.Image_4.Visible = 0;
                case 5
                    app.Image_5.Visible = 0;
                case 6
                    app.Image_6.Visible = 0;
                case 7
                    app.Image_7.Visible = 0;
                case 8
                    app.Image_8.Visible = 0;
                case 9
                    app.Image_9.Visible = 0;
                case 10
                    app.Image_10.Visible = 0;
                case 11
                    app.Image_11.Visible = 0;
                case 12
                    app.Image_12.Visible = 0;
                case 13
                    app.Image_13.Visible = 0;
                case 14
                    app.Image_14.Visible = 0;
                case 15
                    app.Image_15.Visible = 0;
                case 16
                    app.Image_16.Visible = 0;
                case 17
                    app.Image_17.Visible = 0;
                case 18
                    app.Image_18.Visible = 0;
                case 19
                    app.Image_19.Visible = 0;
                case 20
                    app.Image_20.Visible = 0;
                case 21
                    app.Image_21.Visible = 0;
                case 22
                    app.Image_22.Visible = 0;
                case 23
                    app.Image_23.Visible = 0;
                case 24
                    app.Image_24.Visible = 0;
                case 25
                    app.Image_25.Visible = 0;
                case 26
                    app.Image_26.Visible = 0;
                case 27
                    app.Image_27.Visible = 0;
                case 28
                    app.Image_28.Visible = 0;
                case 29
                    app.Image_29.Visible = 0;
                case 30
                    app.Image_30.Visible = 0;
            end
        end
        
        function reset(app, a)
            switch a
                case 1
                    app.Image.ImageSource = 'korean_fish.jpg';
                case 2
                    app.Image_2.ImageSource = 'korean_fish.jpg';
                case 3
                    app.Image_3.ImageSource = 'korean_fish.jpg';
                case 4
                    app.Image_4.ImageSource = 'korean_fish.jpg';
                case 5
                    app.Image_5.ImageSource = 'korean_fish.jpg';
                case 6
                    app.Image_6.ImageSource = 'korean_fish.jpg';
                case 7
                    app.Image_7.ImageSource = 'korean_fish.jpg';
                case 8
                    app.Image_8.ImageSource = 'korean_fish.jpg';
                case 9
                    app.Image_9.ImageSource = 'korean_fish.jpg';
                case 10
                    app.Image_10.ImageSource = 'korean_fish.jpg';
                case 11
                    app.Image_11.ImageSource = 'korean_fish.jpg';
                case 12
                    app.Image_12.ImageSource = 'korean_fish.jpg';
                case 13
                    app.Image_13.ImageSource = 'korean_fish.jpg';
                case 14
                    app.Image_14.ImageSource = 'korean_fish.jpg';
                case 15
                    app.Image_15.ImageSource = 'korean_fish.jpg';
                case 16
                    app.Image_16.ImageSource = 'korean_fish.jpg';  
                case 17
                    app.Image_17.ImageSource = 'korean_fish.jpg';
                case 18
                    app.Image_18.ImageSource = 'korean_fish.jpg';
                case 19
                    app.Image_19.ImageSource = 'korean_fish.jpg';
                case 20
                    app.Image_20.ImageSource = 'korean_fish.jpg';
                case 21
                    app.Image_21.ImageSource = 'korean_fish.jpg';
                case 22
                    app.Image_22.ImageSource = 'korean_fish.jpg';
                case 23
                    app.Image_23.ImageSource = 'korean_fish.jpg';
                case 24
                    app.Image_24.ImageSource = 'korean_fish.jpg';
                case 25
                    app.Image_25.ImageSource = 'korean_fish.jpg';
                case 26
                    app.Image_26.ImageSource = 'korean_fish.jpg';
                case 27
                    app.Image_27.ImageSource = 'korean_fish.jpg';
                case 28
                    app.Image_28.ImageSource = 'korean_fish.jpg';
                case 29
                    app.Image_29.ImageSource = 'korean_fish.jpg';
                case 30
                    app.Image_30.ImageSource = 'korean_fish.jpg';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %亂數產生
            a = randperm(30);
            app.random_list = app.random_list(a);
            tic;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(1) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image.ImageSource = strcat(num2str(app.random_list(1)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(2) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_2.ImageSource = strcat(num2str(app.random_list(2)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(3) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_3.ImageSource = strcat(num2str(app.random_list(3)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(4) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_4.ImageSource = strcat(num2str(app.random_list(4)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(5) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_5.ImageSource = strcat(num2str(app.random_list(5)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(6) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_6.ImageSource = strcat(num2str(app.random_list(6)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_7
        function Image_7Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(7) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_7.ImageSource = strcat(num2str(app.random_list(7)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_8
        function Image_8Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(8) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_8.ImageSource = strcat(num2str(app.random_list(8)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_9
        function Image_9Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(9) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_9.ImageSource = strcat(num2str(app.random_list(9)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_10
        function Image_10Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(10) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_10.ImageSource = strcat(num2str(app.random_list(10)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_11
        function Image_11Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(11) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_11.ImageSource = strcat(num2str(app.random_list(11)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_12
        function Image_12Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(12) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_12.ImageSource = strcat(num2str(app.random_list(12)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_13
        function Image_13Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(13) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_13.ImageSource = strcat(num2str(app.random_list(13)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_14
        function Image_14Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(14) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_14.ImageSource = strcat(num2str(app.random_list(14)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_15
        function Image_15Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(15) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_15.ImageSource = strcat(num2str(app.random_list(15)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_16
        function Image_16Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(16) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_16.ImageSource = strcat(num2str(app.random_list(16)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_17
        function Image_17Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(17) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_17.ImageSource = strcat(num2str(app.random_list(17)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_18
        function Image_18Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(18) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_18.ImageSource = strcat(num2str(app.random_list(18)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_19
        function Image_19Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(19) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_19.ImageSource = strcat(num2str(app.random_list(19)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_20
        function Image_20Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(20) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_20.ImageSource = strcat(num2str(app.random_list(20)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_21
        function Image_21Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(21) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_21.ImageSource = strcat(num2str(app.random_list(21)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_22
        function Image_22Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(22) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_22.ImageSource = strcat(num2str(app.random_list(22)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_23
        function Image_23Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(23) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_23.ImageSource = strcat(num2str(app.random_list(23)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_24
        function Image_24Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(24) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_24.ImageSource = strcat(num2str(app.random_list(24)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_25
        function Image_25Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(25) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_25.ImageSource = strcat(num2str(app.random_list(25)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_26
        function Image_26Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(26) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_26.ImageSource = strcat(num2str(app.random_list(26)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_27
        function Image_27Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(27) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_27.ImageSource = strcat(num2str(app.random_list(27)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_28
        function Image_28Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(28) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_28.ImageSource = strcat(num2str(app.random_list(28)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_29
        function Image_29Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(29) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_29.ImageSource = strcat(num2str(app.random_list(29)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_30
        function Image_30Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(30) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_30.ImageSource = strcat(num2str(app.random_list(30)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'korean_fish.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'korean_fish.jpg';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'korean_fish.jpg';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = 'korean_fish.jpg';
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = 'korean_fish.jpg';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = 'korean_fish.jpg';
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @Image_7Clicked, true);
            app.Image_7.ImageSource = 'korean_fish.jpg';
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @Image_8Clicked, true);
            app.Image_8.ImageSource = 'korean_fish.jpg';
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageClickedFcn = createCallbackFcn(app, @Image_9Clicked, true);
            app.Image_9.ImageSource = 'korean_fish.jpg';
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageClickedFcn = createCallbackFcn(app, @Image_10Clicked, true);
            app.Image_10.ImageSource = 'korean_fish.jpg';
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.ImageClickedFcn = createCallbackFcn(app, @Image_11Clicked, true);
            app.Image_11.ImageSource = 'korean_fish.jpg';
            app.Image_12 = uiimage(app.UIFigure);
            app.Image_12.ImageClickedFcn = createCallbackFcn(app, @Image_12Clicked, true);
            app.Image_12.ImageSource = 'korean_fish.jpg';
            app.Image_13 = uiimage(app.UIFigure);
            app.Image_13.ImageClickedFcn = createCallbackFcn(app, @Image_13Clicked, true);
            app.Image_13.ImageSource = 'korean_fish.jpg';
            app.Image_14 = uiimage(app.UIFigure);
            app.Image_14.ImageClickedFcn = createCallbackFcn(app, @Image_14Clicked, true);
            app.Image_14.ImageSource = 'korean_fish.jpg';
            app.Image_15 = uiimage(app.UIFigure);
            app.Image_15.ImageClickedFcn = createCallbackFcn(app, @Image_15Clicked, true);
            app.Image_15.ImageSource = 'korean_fish.jpg';
            app.Image_16 = uiimage(app.UIFigure);
            app.Image_16.ImageClickedFcn = createCallbackFcn(app, @Image_16Clicked, true);
            app.Image_16.ImageSource = 'korean_fish.jpg';
            app.Image_17 = uiimage(app.UIFigure);
            app.Image_17.ImageClickedFcn = createCallbackFcn(app, @Image_17Clicked, true);
            app.Image_17.ImageSource = 'korean_fish.jpg';
            app.Image_18 = uiimage(app.UIFigure);
            app.Image_18.ImageClickedFcn = createCallbackFcn(app, @Image_18Clicked, true);
            app.Image_18.ImageSource = 'korean_fish.jpg';
            app.Image_19 = uiimage(app.UIFigure);
            app.Image_19.ImageClickedFcn = createCallbackFcn(app, @Image_19Clicked, true);
            app.Image_19.ImageSource = 'korean_fish.jpg';
            app.Image_20 = uiimage(app.UIFigure);
            app.Image_20.ImageClickedFcn = createCallbackFcn(app, @Image_20Clicked, true);
            app.Image_20.ImageSource = 'korean_fish.jpg';
            app.Image_21 = uiimage(app.UIFigure);
            app.Image_21.ImageClickedFcn = createCallbackFcn(app, @Image_21Clicked, true);
            app.Image_21.ImageSource = 'korean_fish.jpg';
            app.Image_22 = uiimage(app.UIFigure);
            app.Image_22.ImageClickedFcn = createCallbackFcn(app, @Image_22Clicked, true);
            app.Image_22.ImageSource = 'korean_fish.jpg';
            app.Image_23 = uiimage(app.UIFigure);
            app.Image_23.ImageClickedFcn = createCallbackFcn(app, @Image_23Clicked, true);
            app.Image_23.ImageSource = 'korean_fish.jpg';
            app.Image_24 = uiimage(app.UIFigure);
            app.Image_24.ImageClickedFcn = createCallbackFcn(app, @Image_24Clicked, true);
            app.Image_24.ImageSource = 'korean_fish.jpg';
            app.Image_25 = uiimage(app.UIFigure);
            app.Image_25.ImageClickedFcn = createCallbackFcn(app, @Image_25Clicked, true);
            app.Image_25.ImageSource = 'korean_fish.jpg';
            app.Image_26 = uiimage(app.UIFigure);
            app.Image_26.ImageClickedFcn = createCallbackFcn(app, @Image_26Clicked, true);
            app.Image_26.ImageSource = 'korean_fish.jpg';
            app.Image_27 = uiimage(app.UIFigure);
            app.Image_27.ImageClickedFcn = createCallbackFcn(app, @Image_27Clicked, true);
            app.Image_27.ImageSource = 'korean_fish.jpg';
            app.Image_28 = uiimage(app.UIFigure);
            app.Image_28.ImageClickedFcn = createCallbackFcn(app, @Image_28Clicked, true);
            app.Image_28.ImageSource = 'korean_fish.jpg';
            app.Image_29 = uiimage(app.UIFigure);
            app.Image_29.ImageClickedFcn = createCallbackFcn(app, @Image_29Clicked, true);
            app.Image_29.ImageSource = 'korean_fish.jpg';
            app.Image_30 = uiimage(app.UIFigure);
            app.Image_30.ImageClickedFcn = createCallbackFcn(app, @Image_30Clicked, true);
            app.Image_30.ImageSource = 'korean_fish.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_difficult

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end