classdef game_easy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EasyUIFigure  matlab.ui.Figure
        Image         matlab.ui.control.Image
        Image_2       matlab.ui.control.Image
        Image_3       matlab.ui.control.Image
        Image_4       matlab.ui.control.Image
        Image_5       matlab.ui.control.Image
        Image_6       matlab.ui.control.Image
        Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0;   % flag決定是否猜對
        random_list = [1,1,2,2,3,3];
        clicked = zeros(1,6);
        jpg = '.jpg';
        complete = 0;
    end
    
    methods (Access = private)
        
        function guess(app)
            guess_answer = 0;   %若猜對則為1
            clicked_image = find(app.clicked);
            if length(clicked_image)>1 && app.random_list(clicked_image(1)) == app.random_list(clicked_image(2)) 
                guess_answer = 1;
                app.invisible(clicked_image(1))
                app.invisible(clicked_image(2))
                app.complete = app.complete +1;
                if app.complete == 3
                    app.Label.Text = strcat('恭喜完成！！花費', string(toc),'秒');
                    % obj = app;
                    % obj.delete;
                    
                end
            elseif length(clicked_image) == 1
                guess_answer = 0;
                app.reset(clicked_image(1));
            else
                guess_answer = 0;
                app.reset(clicked_image(1));
                app.reset(clicked_image(2));
            end
            app.flag = 0;
            app.clicked = zeros(1,6);
        end
        
        function invisible(app, a)
            switch a
                case 1
                    app.Image.Visible = 0;
                case 2
                    app.Image_2.Visible = 0;
                case 3
                    app.Image_3.Visible = 0;
                case 4
                    app.Image_4.Visible = 0;
                case 5
                    app.Image_5.Visible = 0;
                case 6
                    app.Image_6.Visible = 0;
            end
        end
        
        function reset(app, a)
            switch a
                case 1
                    app.Image.ImageSource = 'korean_fish.jpg';
                case 2
                    app.Image_2.ImageSource = 'korean_fish.jpg';
                case 3
                    app.Image_3.ImageSource = 'korean_fish.jpg';
                case 4
                    app.Image_4.ImageSource = 'korean_fish.jpg';
                case 5
                    app.Image_5.ImageSource = 'korean_fish.jpg';
                case 6
                    app.Image_6.ImageSource = 'korean_fish.jpg';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %亂數產生
            a = randperm(6);
            app.random_list = app.random_list(a);
            tic;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(1) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image.ImageSource = strcat(num2str(app.random_list(1)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
            
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(2) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_2.ImageSource = strcat(num2str(app.random_list(2)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(3) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_3.ImageSource = strcat(num2str(app.random_list(3)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(4) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_4.ImageSource = strcat(num2str(app.random_list(4)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(5) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_5.ImageSource = strcat(num2str(app.random_list(5)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(6) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_6.ImageSource = strcat(num2str(app.random_list(6)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EasyUIFigure = uifigure('Visible', 'off');
            app.EasyUIFigure.Name = 'Easy';
            app.Image = uiimage(app.EasyUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'korean_fish.jpg';
            app.Image_2 = uiimage(app.EasyUIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'korean_fish.jpg';
            app.Image_3 = uiimage(app.EasyUIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'korean_fish.jpg';
            app.Image_4 = uiimage(app.EasyUIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = 'korean_fish.jpg';
            app.Image_5 = uiimage(app.EasyUIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = 'korean_fish.jpg';
            app.Image_6 = uiimage(app.EasyUIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = 'korean_fish.jpg';
            app.Label = uilabel(app.EasyUIFigure);
            app.Label.Text = {''; ''};
            % Show the figure after all components are created
            app.EasyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_easy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EasyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EasyUIFigure)
        end
    end
end