classdef game_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        EasyButton       matlab.ui.control.Button
        MediumButton     matlab.ui.control.Button
        DifficultButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EasyButton
        function EasyButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            obj = game_easy;
            waitfor(obj);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: MediumButton
        function MediumButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            obj = game_medium;
            waitfor(obj);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: DifficultButton
        function DifficultButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            obj = game_difficult;
            waitfor(obj);
            app.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '對對碰';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '對對碰';
            app.EasyButton = uibutton(app.UIFigure, 'push');
            app.EasyButton.ButtonPushedFcn = createCallbackFcn(app, @EasyButtonPushed, true);
            app.EasyButton.Text = 'Easy';
            app.MediumButton = uibutton(app.UIFigure, 'push');
            app.MediumButton.ButtonPushedFcn = createCallbackFcn(app, @MediumButtonPushed, true);
            app.MediumButton.Text = 'Medium';
            app.DifficultButton = uibutton(app.UIFigure, 'push');
            app.DifficultButton.ButtonPushedFcn = createCallbackFcn(app, @DifficultButtonPushed, true);
            app.DifficultButton.Text = 'Difficult';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end