classdef game_medium < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        Image_2   matlab.ui.control.Image
        Image_3   matlab.ui.control.Image
        Image_4   matlab.ui.control.Image
        Image_5   matlab.ui.control.Image
        Image_6   matlab.ui.control.Image
        Image_7   matlab.ui.control.Image
        Image_8   matlab.ui.control.Image
        Image_9   matlab.ui.control.Image
        Image_10  matlab.ui.control.Image
        Image_11  matlab.ui.control.Image
        Image_12  matlab.ui.control.Image
        Image_13  matlab.ui.control.Image
        Image_14  matlab.ui.control.Image
        Image_15  matlab.ui.control.Image
        Image_16  matlab.ui.control.Image
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        flag = 0;   % flag決定是否猜對
        random_list = [1,1,2,2,3,3,4,4,5,5,6,6,7,7,8,8];
        clicked = zeros(1,16);
        jpg = '.jpg';
        complete = 0;
    end
    
    methods (Access = private)
        
        function guess(app)
            guess_answer = 0;   %若猜對則為1
            clicked_image = find(app.clicked);
            if length(clicked_image)>1 && app.random_list(clicked_image(1)) == app.random_list(clicked_image(2)) 
                guess_answer = 1;
                app.invisible(clicked_image(1))
                app.invisible(clicked_image(2))
                app.complete = app.complete +1;
                if app.complete == 8
                    app.Label.Text = strcat('恭喜完成！！花費', string(toc),'秒');
                end
            elseif length(clicked_image) == 1
                guess_answer = 0;
                app.reset(clicked_image(1));
            else
                guess_answer = 0;
                app.reset(clicked_image(1));
                app.reset(clicked_image(2));
            end
            app.flag = 0;
            app.clicked = zeros(1,6);
        end
        function invisible(app, a)
            switch a
                case 1
                    app.Image.Visible = 0;
                case 2
                    app.Image_2.Visible = 0;
                case 3
                    app.Image_3.Visible = 0;
                case 4
                    app.Image_4.Visible = 0;
                case 5
                    app.Image_5.Visible = 0;
                case 6
                    app.Image_6.Visible = 0;
                case 7
                    app.Image_7.Visible = 0;
                case 8
                    app.Image_8.Visible = 0;
                case 9
                    app.Image_9.Visible = 0;
                case 10
                    app.Image_10.Visible = 0;
                case 11
                    app.Image_11.Visible = 0;
                case 12
                    app.Image_12.Visible = 0;
                case 13
                    app.Image_13.Visible = 0;
                case 14
                    app.Image_14.Visible = 0;
                case 15
                    app.Image_15.Visible = 0;
                case 16
                    app.Image_16.Visible = 0;
            end
        end
        
        
        function reset(app, a)
            switch a
                case 1
                    app.Image.ImageSource = 'korean_fish.jpg';
                case 2
                    app.Image_2.ImageSource = 'korean_fish.jpg';
                case 3
                    app.Image_3.ImageSource = 'korean_fish.jpg';
                case 4
                    app.Image_4.ImageSource = 'korean_fish.jpg';
                case 5
                    app.Image_5.ImageSource = 'korean_fish.jpg';
                case 6
                    app.Image_6.ImageSource = 'korean_fish.jpg';
                case 7
                    app.Image_7.ImageSource = 'korean_fish.jpg';
                case 8
                    app.Image_8.ImageSource = 'korean_fish.jpg';
                case 9
                    app.Image_9.ImageSource = 'korean_fish.jpg';
                case 10
                    app.Image_10.ImageSource = 'korean_fish.jpg';
                case 11
                    app.Image_11.ImageSource = 'korean_fish.jpg';
                case 12
                    app.Image_12.ImageSource = 'korean_fish.jpg';
                case 13
                    app.Image_13.ImageSource = 'korean_fish.jpg';
                case 14
                    app.Image_14.ImageSource = 'korean_fish.jpg';
                case 15
                    app.Image_15.ImageSource = 'korean_fish.jpg';
                case 16
                    app.Image_16.ImageSource = 'korean_fish.jpg';                      
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %亂數產生
            a = randperm(16);
            app.random_list = app.random_list(a);
            tic;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(1) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image.ImageSource = strcat(num2str(app.random_list(1)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(2) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_2.ImageSource = strcat(num2str(app.random_list(2)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(3) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_3.ImageSource = strcat(num2str(app.random_list(3)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(4) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_4.ImageSource = strcat(num2str(app.random_list(4)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_5
        function Image_5Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(5) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_5.ImageSource = strcat(num2str(app.random_list(5)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_6
        function Image_6Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(6) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_6.ImageSource = strcat(num2str(app.random_list(6)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_7
        function Image_7Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(7) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_7.ImageSource = strcat(num2str(app.random_list(7)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_8
        function Image_8Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(8) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_8.ImageSource = strcat(num2str(app.random_list(8)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_9
        function Image_9Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(9) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_9.ImageSource = strcat(num2str(app.random_list(9)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_10
        function Image_10Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(10) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_10.ImageSource = strcat(num2str(app.random_list(10)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_11
        function Image_11Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(11) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_11.ImageSource = strcat(num2str(app.random_list(11)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_12
        function Image_12Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(12) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_12.ImageSource = strcat(num2str(app.random_list(12)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_13
        function Image_13Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(13) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_13.ImageSource = strcat(num2str(app.random_list(13)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_14
        function Image_14Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(14) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_14.ImageSource = strcat(num2str(app.random_list(14)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_15
        function Image_15Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(15) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_15.ImageSource = strcat(num2str(app.random_list(15)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end

        % Image clicked function: Image_16
        function Image_16Clicked(app, event)
            app.flag = app.flag + 1;
            app.clicked(16) = 1;
            %app.Label.Text = num2str(app.flag);
            app.Image_16.ImageSource = strcat(num2str(app.random_list(16)),app.jpg);
            if app.flag == 2
                pause(0.2);
                app.guess;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'korean_fish.jpg';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = 'korean_fish.jpg';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = 'korean_fish.jpg';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = 'korean_fish.jpg';
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageClickedFcn = createCallbackFcn(app, @Image_5Clicked, true);
            app.Image_5.ImageSource = 'korean_fish.jpg';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageClickedFcn = createCallbackFcn(app, @Image_6Clicked, true);
            app.Image_6.ImageSource = 'korean_fish.jpg';
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageClickedFcn = createCallbackFcn(app, @Image_7Clicked, true);
            app.Image_7.ImageSource = 'korean_fish.jpg';
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageClickedFcn = createCallbackFcn(app, @Image_8Clicked, true);
            app.Image_8.ImageSource = 'korean_fish.jpg';
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageClickedFcn = createCallbackFcn(app, @Image_9Clicked, true);
            app.Image_9.ImageSource = 'korean_fish.jpg';
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageClickedFcn = createCallbackFcn(app, @Image_10Clicked, true);
            app.Image_10.ImageSource = 'korean_fish.jpg';
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.ImageClickedFcn = createCallbackFcn(app, @Image_11Clicked, true);
            app.Image_11.ImageSource = 'korean_fish.jpg';
            app.Image_12 = uiimage(app.UIFigure);
            app.Image_12.ImageClickedFcn = createCallbackFcn(app, @Image_12Clicked, true);
            app.Image_12.ImageSource = 'korean_fish.jpg';
            app.Image_13 = uiimage(app.UIFigure);
            app.Image_13.ImageClickedFcn = createCallbackFcn(app, @Image_13Clicked, true);
            app.Image_13.ImageSource = 'korean_fish.jpg';
            app.Image_14 = uiimage(app.UIFigure);
            app.Image_14.ImageClickedFcn = createCallbackFcn(app, @Image_14Clicked, true);
            app.Image_14.ImageSource = 'korean_fish.jpg';
            app.Image_15 = uiimage(app.UIFigure);
            app.Image_15.ImageClickedFcn = createCallbackFcn(app, @Image_15Clicked, true);
            app.Image_15.ImageSource = 'korean_fish.jpg';
            app.Image_16 = uiimage(app.UIFigure);
            app.Image_16.ImageClickedFcn = createCallbackFcn(app, @Image_16Clicked, true);
            app.Image_16.ImageSource = 'korean_fish.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game_medium

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end